classdef EKG_software < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        exportFormatDropDown            matlab.ui.control.DropDown
        RozszerzenieplikuDropDownLabel  matlab.ui.control.Label
        exportButton                    matlab.ui.control.Button
        ObsugabibliotekiLabel           matlab.ui.control.Label
        ObsugobecnegopomiaruLabel       matlab.ui.control.Label
        pacjentEditField                matlab.ui.control.EditField
        DanepacjentaLabel               matlab.ui.control.Label
        instrukcjaButton                matlab.ui.control.Button
        backToRealTimeButton            matlab.ui.control.Button
        ZoomOutButton                   matlab.ui.control.Button
        ZoomInButton                    matlab.ui.control.Button
        loadButtonButton                matlab.ui.control.Button
        saveButton                      matlab.ui.control.Button
        stopPomiarButton                matlab.ui.control.Button
        startPomiarButton               matlab.ui.control.Button
        statButton                      matlab.ui.control.Button
        startButton                     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
      ports = serialportlist("available");       % Nazwa portu szeregowego do komunikacji z urządzeniem EKG zmiana - udziewczyn 9 u mnie 3
      portName string    
      baudRate = 115200;         % Prędkość transmisji szeregowej
      running = false;           % Flaga, czy trwa pomiar w czasie rzeczywistym
      

      historyMode = false;
      referenceVoltage = 3.3;   % napięcie odniesienia Arduino
      adcResolution = 1023;     % dla klasycznego Arduino (10-bit)
      userZoom = false;         % czy użytkownik używa zooma

      measurementTimer   % timer do odczytu EKG
    
      s;                         % Obiekt serialport (połączenie z urządzeniem)
      ecgData = [];              % Wektor sygnału EKG
      t = [];                    % Wektor czasów odpowiadających próbkom EKG
      t0;                        % Czas rozpoczęcia pomiaru (referencyjny)
    
      plotHandle    % uchwyt do linii wykresu EKG
      pomiarStartIdx = [];       % Indeks rozpoczęcia wybranego pomiaru do zapisu
      pomiarStopIdx = [];        % Indeks zakończenia wybranego pomiaru
    
      analysisAppInstance        % Uchwyty do drugiej aplikacji - analitycznej 

      K = 0.4;   % współczynnik skali osi Y (zoom)

     end
    
    methods (Access = private)

   function openAnalysisApp(app)
   
        % Tworzymy nową instancję drugiej aplikacj
        app.analysisAppInstance = app1_addv3();

   end
function wyborPortuINapiecia(app)

    d = uifigure('Name','Konfiguracja EKG','Position',[500 300 400 300]);

    % Wybieranie portu COM z którego pobiorę sygnał
    uilabel(d,'Text','Wybierz port COM:', 'Position',[30 240 200 22],'FontName','Ebrima','FontWeight', 'bold');

    app.ports = serialportlist("available");

    if isempty(app.ports)
        app.ports = "Brak dostępnych portów";
    end

    portDrop = uidropdown(d,...
        'Items',app.ports,...
        'Position',[30 210 200 25],'FontName','Ebrima','FontWeight', 'bold');

    % Wybieranie napięcia właściwego danemu typowi Arduino 
    uilabel(d,'Text','Wybierz napięcie Arduino:','Position',[30 170 250 22],'FontName','Ebrima','FontWeight', 'bold');

    voltageDrop = uidropdown(d,'Items',{'3.3V','5V','Inne'},'Position',[30 140 200 25],'FontName','Ebrima','FontWeight', 'bold');

    voltageEdit = uieditfield(d,'numeric', 'Position',[250 140 80 25],'Visible','off');

    voltageDrop.ValueChangedFcn = @(src,event) set(voltageEdit,'Visible', strcmp(src.Value,'Inne'));

    % Wybór rozdzielczości ADC - bity
    uilabel(d,'Text','Wybierz rozdzielczość maksymalną ADC:', 'Position',[30 100 300 22],'FontName','Ebrima','FontWeight','bold');

    adcDrop = uidropdown(d, 'Items', {'1023','4095','Inne'}, 'Position',[30 70 200 25], 'FontName','Ebrima','FontWeight','bold');

    adcEdit = uieditfield(d,'numeric','Position',[250 70 80 25], 'Visible','off');

    adcDrop.ValueChangedFcn = @(src,event) set(adcEdit,'Visible', strcmp(src.Value,'Inne'));

    % Zatwierdzenie wyboru
    uibutton(d,'Text','OK','Position',[140 10 120 40],'FontName','Ebrima','FontWeight', 'bold','ButtonPushedFcn',@(btn,event) zatwierdzKonfiguracje(app,portDrop,voltageDrop,voltageEdit,adcDrop,adcEdit,d));
end

function zatwierdzKonfiguracje(app, portDrop, voltageDrop, voltageEdit, adcDrop, adcEdit, d)

    if portDrop.Value == "Brak dostępnych portów"
        uialert(app.UIFigure,'Brak dostępnych portów COM','Błąd');
        return;
    end

    app.portName = portDrop.Value;

    % Napięcie referencyjne
    switch voltageDrop.Value
        case '3.3V'
            app.referenceVoltage = 3.3;
        case '5V'
            app.referenceVoltage = 5.0;
        case 'Inne'
            app.referenceVoltage = voltageEdit.Value;
    end

    % Rozdzielczość ADC (Maksymalna wartość)
    switch adcDrop.Value
        case '1023'
            app.adcResolution = 1023;
        case '4095'
            app.adcResolution = 4095;
        case 'Inne'
            app.adcResolution = adcEdit.Value;
    end

    if app.adcResolution <= 0
        uialert(app.UIFigure,'Nieprawidłowa rozdzielczość ADC','Błąd');
        return;
    end

    delete(d);
    pokazOknoGotowosci(app);
end

function pokazOknoGotowosci(app)
    % Tworzymy prosty interfejs UI pytający, czy użytkownik jest gotowy do
    % rozpoczęcia pomiaru (chodzi o to by przypomniec uztkownikowi o
    % podpieciu EKG).
    d = uifigure('Name','Rozpoczęcie pomiaru', 'Position',[500 300 350 150]);
    uilabel(d,'Text','Czy jesteś gotowy do rozpoczęcia pomiaru EKG?',  'FontName','Ebrima','FontWeight', 'bold', 'Position',[20 80 310 40],'FontSize',12);
    uibutton(d,'Text','Rozpocznij','Position',[120 20 100 40], 'FontName','Ebrima','FontWeight', 'bold', 'ButtonPushedFcn',@(btn,event) app.zamknijIOdczyt(btn));
end

function zamknijIOdczyt(app, btn)
    % Funkcja wywoływana po kliknięciu przycisku "Rozpocznij" w oknie
    % gotowości - patrz funckja powyżej
    fig = btn.Parent; 
    delete(fig); % zamykamy okno gotowości
    % Próba otwarcia portu szeregowego do pomiaru - jak się nie uda
    % wyskakuje okienko
    try
        app.s = serialport(app.portName, app.baudRate);
        flush(app.s);
    catch
        uialert(app.UIFigure, ['Nie można otworzyć portu ' app.portName],'Błąd');
        return;
    end
    % Reset danych EKG i czasu startowego
    app.ecgData = [];
    app.t = [];
    app.t0 = datetime('now');
end

function readECGSample(app)
    %Funckja do odczytu sygnału EKG - przeliczająca go względme napięcia
    %referencyjnego

if ~app.running || isempty(app.s) || ~isvalid(app.s)
    return;
end

if app.s.NumBytesAvailable == 0
    return;
end

val = str2double(readline(app.s));
if isnan(val)
    return;
end

voltage = (val / app.adcResolution) * app.referenceVoltage;

app.ecgData(end+1) = voltage;
app.t(end+1) = seconds(datetime('now') - app.t0);

tEnd = app.t(end);
if tEnd <= 10
        xlim(app.UIAxes, [0 10]);          
else
        xlim(app.UIAxes, [tEnd-10 tEnd]);  % Wykres zaczyna sie "poruszać" - przesuwac po upływie dopiero 10 sekund
end
    
    if numel(app.ecgData) > 500
        y = app.ecgData(end-499:end);
        center = mean(y);
        range = max(y) - min(y);
        ylim(app.UIAxes, [center - app.K*range, center + app.K*range]); %parametr K - używany prz Zoomowaniu
    end

    if mod(numel(app.ecgData),5) == 0
        set(app.plotHandle,'XData',app.t,'YData',app.ecgData);
        drawnow limitrate
    end
end


function setUserZoomTrue(app)
    app.userZoom = true;
end

function applyZoomNow(app) %Funckja umożliwiajaca zoomowanie także "nieprzesuwających" sie EKG - czyli EKg z Histori Pacjenta lub po zakończeniu pomiaru

if isempty(app.ecgData)
    return;
end

y = app.ecgData;
center = mean(y);
range = max(y) - min(y);

if range == 0
    range = 0.01;
end

ylim(app.UIAxes, [center - app.K*range, center + app.K*range]);

end

    % Tworzę niezależne (od głównej aplikacji) okienko z instrukcją.
    function showInstructionWindow(app)
    % Próbuje wczytać plik instrukcji tekstowej
    if isfile('EKG_instrukcja.txt')
        txt = fileread('EKG_KERMA_PROJECT_INSTRUKCJA.txt');
    else
        txt = "Nie znaleziono pliku z instrukcją.";
    end

     % Utwórz okienko instrukcji
     f = uifigure('Name','Instrukcja','Position',[400 200 600 600]);
     f.WindowStyle = 'normal'; % dzięki temu okienko zostanie otwarte nawet po zamknięciu aplikacji

     % Pole tekstowe z przewijaniem (tylko do odczytu instrukcji)
     uitextarea(f, 'Value', splitlines(txt),'Editable', 'off','FontName', 'Ebrima', 'FontSize', 12,'Position', [20 20 560 560]);
      end

        
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Funkcja ta jest wywoływana po utworzeniu interfejsu aplikacji
            % Przygotowuje wykres
            colormap(app.UIAxes,'winter');
            grid(app.UIAxes,'on');
            hold(app.UIAxes,'on');
            app.backToRealTimeButton.Visible = 'off';
            % Wyświetla okno gotowości
            wyborPortuINapiecia(app);
            % Parametry Zoomu
            z = zoom(app.UIFigure);
            z.ActionPostCallback = @(~,~) app.setUserZoomTrue();

            p = pan(app.UIFigure);
            p.ActionPostCallback = @(~,~) app.setUserZoomTrue();
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
    if app.historyMode
        uialert(app.UIFigure, 'Najpierw wróć do trybu czasu rzeczywistego (kliknij "Powrót").', 'Nie można rozpocząć pomiaru'); %Sprawdzam czy mierzę w czasie rzeczywistym czy mam na ekranie - historię pomiaru
       return;
    end

    % Sprawdzenie połączenia z portem wybranym portem COM
    if isempty(app.s) || ~isvalid(app.s)
        uialert(app.UIFigure,'Brak połączenia z portem COM – sprawdź połączenie','Uwaga');
        return;
    end

    %  Przygotowanie wykresu 
    cla(app.UIAxes);
    hold(app.UIAxes, 'on');
    grid(app.UIAxes, 'on');
    ylim(app.UIAxes, [0.5 1.9]);   % domyślna skala EKG
    colormap(app.UIAxes, 'winter');

app.running = true;
app.userZoom = false;

app.ecgData = [];
app.t = [];
app.t0 = datetime('now');

%Towrzenie i zmiana wykresu
app.plotHandle = plot(app.UIAxes, NaN, NaN, 'Color',[0 0.5 0], 'LineWidth',1.5);

%Przesuwanie wykresu jest "ogarniane" przez timer
app.measurementTimer = timer( 'ExecutionMode','fixedRate','Period',0.01,'TimerFcn',@(~,~)app.readECGSample() );

start(app.measurementTimer);
        end

        % Button pushed function: statButton
        function statButtonPushed(app, event)
    % Funkcja zatrzymuje pomiar w czasie rzeczywistym
app.running = false;

if ~isempty(app.measurementTimer) && isvalid(app.measurementTimer)
    stop(app.measurementTimer);
    delete(app.measurementTimer);
end
% Nie czyścimy danych ani wykresu – tylko zatrzymujemy
uialert(app.UIFigure,'Pomiar zatrzymany. Dane widoczne na wykresie.','Stop');
        end

        % Button pushed function: startPomiarButton
        function startPomiarButtonPushed(app, event)
    % Zapisuję indeks startu pomiaru dla późniejszego eksportu
    if isempty(app.ecgData)
        uialert(app.UIFigure,'Brak danych do rozpoczęcia pomiaru','Uwaga');
        return;
    end
    app.pomiarStartIdx = length(app.ecgData);
    uialert(app.UIFigure,'Rozpoczęto pomiar','Pomiar'); % Okienko informujące o rozpoczeciu pomiaru

        end

        % Button pushed function: stopPomiarButton
        function stopPomiarButtonPushed(app, event)
    % Zapisuje indeks końca pomiaru dla późniejszego eksportu
    if isempty(app.pomiarStartIdx)
        uialert(app.UIFigure,'Najpierw rozpocznij pomiar','Uwaga');
        return;
    end

    app.pomiarStopIdx = length(app.ecgData);
    uialert(app.UIFigure,'Zakończono pomiar — możesz zapisać','Pomiar'); %Okienko informujące o zakończeniu pomiaru
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
    % Zapis wybranego fragmentu pomiaru do pliku .mat pacjenta
    %Różne wyjątki, zapobiegające zapisaniu pustego pliku itp.
    if isempty(app.pomiarStartIdx) || isempty(app.pomiarStopIdx)
        uialert(app.UIFigure,'Najpierw wybierz zakres pomiaru','Uwaga');
        return;
    end
    name = strtrim(app.pacjentEditField.Value);
    if name == ""
        uialert(app.UIFigure,'Podaj nazwę pacjenta przed zapisem!','Uwaga');
        return;
    end
    if app.pomiarStopIdx <= app.pomiarStartIdx
        uialert(app.UIFigure,'Nieprawidłowy zakres pomiaru','Błąd');
        return;
    end
    % Przygotowanie sygnału i czasu do zapisu
    sygnal = app.ecgData(app.pomiarStartIdx:app.pomiarStopIdx);
    czas = app.t(app.pomiarStartIdx:app.pomiarStopIdx);
    % Nazwa pliku historii pacjenta
    fname = sprintf('Historia_%s.mat',name);
    if isfile(fname)
        load(fname,"historia");
        idx = numel(historia) + 1;
    else
        idx = 1;
    end
    %Dane i ich zapis do stworzonego pliku
    historia(idx).czasZapis = datetime('now');
    historia(idx).czas = czas;
    historia(idx).sygnal = sygnal;
    save(fname,"historia");
    %Infomacja o ukończeniu zapisu
    uialert(app.UIFigure,sprintf('Zapisano pomiar pacjenta: %s',name),'OK');

        end

        % Button pushed function: loadButtonButton
        function loadButtonButtonPushed(app, event)
    % Wczytuje zapisany pomiar pacjenta i rysuje go na wykresie
    app.running = false; % zatrzymaj aktualny pomiar
    app.historyMode = true;
    name = strtrim(app.pacjentEditField.Value); % pobranie nazwy pacjenta z pola tekstowego
    if name == ""
        uialert(app.UIFigure,'Podaj nazwę pacjenta do wczytania','Uwaga');
        return;
    end
    fname = sprintf('Historia_%s.mat',name);  % utworzenie nazwy pliku historii
    if ~isfile(fname)
        uialert(app.UIFigure,'Brak historii dla tego pacjenta','Błąd');
        return;
    end
    load(fname,"historia");  % wczytanie danych z pliku .mat
    cla(app.UIAxes);  % wyczyszczenie wykresu przed rysowaniem
    %Rysowanie historii pomiaru pacjenta
    allTimes = [];
    allSignals = [];

for i = 1:numel(historia)
    t_rel = historia(i).czas - historia(i).czas(1); % czas względny od 0
    plot(app.UIAxes, t_rel, historia(i).sygnal, 'LineWidth', 1.5);  % rysuje linię (pomiar)
    hold(app.UIAxes, 'on'); % pozwala rysować kolejne linie (pomiary) na tym samym wykresie 

    % zbieramy dane do ustalenia zakresu
    allTimes = [allTimes; t_rel(:)];
    allSignals = [allSignals; historia(i).sygnal(:)];
 end

    xlabel(app.UIAxes, 'Czas [s]');
    ylabel(app.UIAxes, 'Odebrany sygnał');
    title(app.UIAxes, sprintf('Historia pacjenta: %s', name));
 
    % Automatyczne dopasowanie osi X i Y
    if ~isempty(allTimes) && ~isempty(allSignals)
        xlim(app.UIAxes, [0 10]);
        ylim(app.UIAxes, [0.8*min(allSignals), 1.2*max(allSignals)]);
    end
    app.UIAxes.XLimMode = 'manual';
    app.UIAxes.YLimMode = 'manual';

    app.backToRealTimeButton.Visible = 'on';

    uialert(app.UIFigure,'Załadowano zapisany pomiar. Aby wrócić do pomiaru w czasie rzeczywistym kliknij "Powrót do pomiaru w czasie rzeczywistym".','Tryb historii');

        end

        % Button pushed function: ZoomInButton
        function ZoomInButtonPushed(app, event)
    %Funkcja przybliżajaca      
    
    app.userZoom = true;

    app.K = max(0.1, app.K - 0.1);   % zmniejszamy skalę, ale nie poniżej 0.1

    applyZoomNow(app);

        end

        % Button pushed function: ZoomOutButton
        function ZoomOutButtonPushed(app, event)
    % Funkcja oddalająca

    app.userZoom = true;

    app.K = min(2.0, app.K + 0.1);   % zwiększ skalę, ale nie wiecje niż dwukrotnie

    applyZoomNow(app);

        end

        % Button pushed function: backToRealTimeButton
        function backToRealTimeButtonPushed(app, event)

%Wróć do pierwotnej konfiguracji parametró i flag - rozpocznij kolejny
%pomair w czasie rzeczywistym
app.historyMode = false;
app.userZoom = false;
app.K = 0.4;

cla(app.UIAxes);
title(app.UIAxes, 'Pomiar EKG w czasie rzeczywistym');
app.backToRealTimeButton.Visible = 'off';

% zatrzymaj stary timer jeśli istnieje
if ~isempty(app.measurementTimer) && isvalid(app.measurementTimer)
    stop(app.measurementTimer);
    delete(app.measurementTimer);
end

app.running = true;
app.ecgData = [];
app.t = [];
app.t0 = datetime('now');

app.plotHandle = plot(app.UIAxes, NaN, NaN, 'Color',[0 0.5 0], 'LineWidth',1.5);

app.measurementTimer = timer( 'ExecutionMode','fixedRate', 'Period',0.01,'TimerFcn',@(~,~)app.readECGSample() );

start(app.measurementTimer);

    
        end

        % Button pushed function: instrukcjaButton
        function instrukcjaButtonPushed(app, event)
        
            showInstructionWindow(app);  % wyświetla okienko z instrukcją dla użytkownika
    
        end

        % Button pushed function: exportButton
        function exportButtonPushed(app, event)


    % Sprawdzam nazwę pacjenta
    name = strtrim(app.pacjentEditField.Value);
    if name == ""
        uialert(app.UIFigure,'Podaj nazwę pacjenta!','Błąd');
        return;
    end

    % Sprawdzam czy istnieje plik historii - plik z jego pomiarem - chodzi
    % o to ża najpierw trzeba rozpoczcząć i zakończyć pomiar i go zapisać, a dopier potem
    % można go wyeksportować
    fname = sprintf('Historia_%s.mat', name);
    if ~isfile(fname)
        uialert(app.UIFigure,'Nie znaleziono historii pomiarów dla podanej nazwy.','Błąd');
        return;
    end

    % Wczytuję plik z historii
    load(fname, "historia");

    % Biorę ostatni zapisany pomiar
    idx = numel(historia);

    czas = historia(idx).czas(:);
    sygnal = historia(idx).sygnal(:);

    % Sporządam tabelę zapisu
    T = table(czas, sygnal);

    % Wybieram format zapisu: txt, csv lub xlsx
    format = app.exportFormatDropDown.Value;

    switch format

        case 'TXT'
            outName = sprintf('Pomiar_%s.txt', name);
            writetable(T, outName, 'Delimiter','\t');
            uialert(app.UIFigure, ['Zapisano jako TXT: ' outName], 'OK');

        case 'CSV'
            outName = sprintf('Pomiar_%s.csv', name);
            writetable(T, outName);
            uialert(app.UIFigure, ['Zapisano jako CSV: ' outName], 'OK');

        case 'XLSX'
            outName = sprintf('Pomiar_%s.xlsx', name);
            writetable(T, outName);
            uialert(app.UIFigure, ['Zapisano jako XLSX: ' outName], 'OK');

    end

 
        end

        % Callback function
        function analysisButtonPushed(app, event)

        end

        % Callback function
        function savePlotButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pomiar EKG')
            xlabel(app.UIAxes, 'Czas')
            ylabel(app.UIAxes, 'Odebrany sygnał')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'nebula')
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'Rozpocznj pomiar';
            app.statButton = uibutton(app.UIFigure, 'push');
            app.statButton.ButtonPushedFcn = createCallbackFcn(app, @statButtonPushed, true);
            app.statButton.Text = 'Zakończ pomiar';
            app.startPomiarButton = uibutton(app.UIFigure, 'push');
            app.startPomiarButton.ButtonPushedFcn = createCallbackFcn(app, @startPomiarButtonPushed, true);
            app.startPomiarButton.Text = 'Rozpocznij rejestrację pomiaru';
            app.stopPomiarButton = uibutton(app.UIFigure, 'push');
            app.stopPomiarButton.ButtonPushedFcn = createCallbackFcn(app, @stopPomiarButtonPushed, true);
            app.stopPomiarButton.Text = 'Zakończ rejestrację pomiaru';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'Zapisz pomiar';
            app.loadButtonButton = uibutton(app.UIFigure, 'push');
            app.loadButtonButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonButtonPushed, true);
            app.loadButtonButton.Text = 'Zobacz pomiar';
            app.ZoomInButton = uibutton(app.UIFigure, 'push');
            app.ZoomInButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomInButtonPushed, true);
            app.ZoomInButton.Text = 'Przybliż wykres';
            app.ZoomOutButton = uibutton(app.UIFigure, 'push');
            app.ZoomOutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomOutButtonPushed, true);
            app.ZoomOutButton.Text = 'Oddal wykres';
            app.backToRealTimeButton = uibutton(app.UIFigure, 'push');
            app.backToRealTimeButton.ButtonPushedFcn = createCallbackFcn(app, @backToRealTimeButtonPushed, true);
            app.backToRealTimeButton.Text = 'Powrót do pomiaru w czasie rzeczywistym';
            app.instrukcjaButton = uibutton(app.UIFigure, 'push');
            app.instrukcjaButton.ButtonPushedFcn = createCallbackFcn(app, @instrukcjaButtonPushed, true);
            app.instrukcjaButton.Text = 'Jak korzystać z aplikacji?';
            app.DanepacjentaLabel = uilabel(app.UIFigure);
            app.DanepacjentaLabel.Text = 'Dane pacjenta:';
            app.pacjentEditField = uieditfield(app.UIFigure, 'text');
            app.ObsugobecnegopomiaruLabel = uilabel(app.UIFigure);
            app.ObsugobecnegopomiaruLabel.Text = 'Obsług obecnego pomiaru';
            app.ObsugabibliotekiLabel = uilabel(app.UIFigure);
            app.ObsugabibliotekiLabel.Text = 'Obsługa biblioteki';
            app.exportButton = uibutton(app.UIFigure, 'push');
            app.exportButton.ButtonPushedFcn = createCallbackFcn(app, @exportButtonPushed, true);
            app.exportButton.Text = 'Eksportuj dane';
            app.RozszerzenieplikuDropDownLabel = uilabel(app.UIFigure);
            app.RozszerzenieplikuDropDownLabel.Text = 'Rozszerzenie pliku';
            app.exportFormatDropDown = uidropdown(app.UIFigure);
            app.exportFormatDropDown.Items = {'TXT', 'CSV', 'XLSX'};
            app.exportFormatDropDown.Value = 'TXT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EKG_software

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end